classdef GUI_feedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        StopButton              matlab.ui.control.Button
        CylesEditField          matlab.ui.control.NumericEditField
        CylesEditFieldLabel     matlab.ui.control.Label
        WorktimeEditField       matlab.ui.control.NumericEditField
        WorktimeEditFieldLabel  matlab.ui.control.Label
        ResttimeEditField       matlab.ui.control.NumericEditField
        ResttimeEditFieldLabel  matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        StartButton             matlab.ui.control.Button
        FeedbackGUILabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        stop = 0;
        server = tcpserver("127.0.0.1", 65432);
        
    end
    
    methods (Access = private)
        
        function block(app)
            %Connect to server
            

            %baseline
            tic;
            score = 8;
            hand = 17;
            while (toc < app.ResttimeEditField.Value)
                app.TextArea.Value = 'Baseline';
                pause(.01); 
                if(app.stop ==1)
                    return;
                end
            end
           

            %run the cycles
            for i=1:app.CylesEditField.Value
                tic;

                % rest -- indicate if at a rest state
                while (toc < app.ResttimeEditField.Value)
                    app.TextArea.Value = 'rest';
                    pause(.1);
                    if(app.stop ==1)
                        disp("stop");
                    end
                    hand = 17;
                    app.Image2.ImageSource="images/17.png";
                    app.Image.ImageSource="images/rest.png";
                end
                 tic;
                while (toc < app.WorktimeEditField.Value)
                    app.TextArea.Value = 'work';
                    
                    
                    %read info from server
                    hold = 0;
                    while(hold == 0)
                        if app.server.NumBytesAvailable > 0
                            data = read(app.server, app.server.NumBytesAvailable, "string");
                            disp("Received: " + data)
                            hold = 1;
                        end
                    end    
                    input = str2double(data);

                    if input == 0
                        score = max(0,score-1);
                        hand = 17;
                        text = "images/"+score+".png";
                        app.Image.ImageSource= text;
                        hand_text = "images/"+ hand +".png";
                        app.Image2.ImageSource = hand_text;
                    else
                        score = min(15,score +1);
                        %move up one and loop back to start
                        %hand = hand +1;
                        %if (hand == 24)
                        %    hand = 17;
                        %end
                        text = "images/"+score+".png";
                        app.Image.ImageSource = text;
                        hand_text = "images/"+ 18 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.05);
                        hand_text = "images/"+ 19 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.05);
                        hand_text = "images/"+ 20 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.05);
                        hand_text = "images/"+ 21 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.05);
                        hand_text = "images/"+ 22 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.05);
                        hand_text = "images/"+ 23 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.13);
                        hand_text = "images/"+ 22 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.03);
                        hand_text = "images/"+ 21 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.03);
                        hand_text = "images/"+ 20 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.03);
                        hand_text = "images/"+ 19 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.03);
                        hand_text = "images/"+ 18 +".png";
                        app.Image2.ImageSource = hand_text;
                        pause(.03);
                        hand_text = "images/"+ 17 +".png";
                        app.Image2.ImageSource = hand_text;


                        %hand_text = "images/"+ hand +".png";
                        
                        %app.Image2.ImageSource = hand_text;
                    end
                    if(app.stop ==1)
                    return;
                    end
                    pause(1);
                end


            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.block()
            %Reset reseter
            app.stop = 0;       
            app.TextArea.Value = 'Done';
            pause(3);
            app.TextArea.Value = 'Press start to begin experiemt';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = 1;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FeedbackGUILabel = uilabel(app.UIFigure);
            app.FeedbackGUILabel.Text = 'Feedback GUI';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Press start to begin experiemt'};
            app.ResttimeEditFieldLabel = uilabel(app.UIFigure);
            app.ResttimeEditFieldLabel.Text = 'Rest time';
            app.ResttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResttimeEditField.Value = 30;
            app.WorktimeEditFieldLabel = uilabel(app.UIFigure);
            app.WorktimeEditFieldLabel.Text = 'Work time';
            app.WorktimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorktimeEditField.Value = 30;
            app.CylesEditFieldLabel = uilabel(app.UIFigure);
            app.CylesEditFieldLabel.Text = 'Cyles';
            app.CylesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CylesEditField.Value = 10;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', '8.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', '16.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_feedback

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end